classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        TabGroup5                   matlab.ui.container.TabGroup
        AllFilesTab                 matlab.ui.container.Tab
        AllFilesListBox             matlab.ui.control.ListBox
        LastUsedTab                 matlab.ui.container.Tab
        LastUsedList                matlab.ui.control.ListBox
        TabGroup4                   matlab.ui.container.TabGroup
        PropiedadesTab              matlab.ui.container.Tab
        PianoTab                    matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        GrabarTab                   matlab.ui.container.Tab
        OperarPanel                 matlab.ui.container.Panel
        LEditField                  matlab.ui.control.NumericEditField
        LEditFieldLabel             matlab.ui.control.Label
        MEditField                  matlab.ui.control.NumericEditField
        MEditFieldLabel             matlab.ui.control.Label
        InterpolarButton            matlab.ui.control.Button
        DiezmarButton               matlab.ui.control.Button
        MulButton                   matlab.ui.control.Button
        SumaButton                  matlab.ui.control.Button
        Switch                      matlab.ui.control.Switch
        GenerarPanel                matlab.ui.container.Panel
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        DuracionsEditField          matlab.ui.control.NumericEditField
        DuracionsEditFieldLabel     matlab.ui.control.Label
        AmplitudEditField           matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel      matlab.ui.control.Label
        FrecuenciaHzEditField       matlab.ui.control.NumericEditField
        FrecuenciaHzEditFieldLabel  matlab.ui.control.Label
        SealDropDown                matlab.ui.control.DropDown
        SealDropDownLabel           matlab.ui.control.Label
        GrabarPanel                 matlab.ui.container.Panel
        Button_11                   matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        ReproducirPanel             matlab.ui.container.Panel
        Button_10                   matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        FiltarTab                   matlab.ui.container.Tab
        RuidoTab                    matlab.ui.container.Tab
        TabGroup                    matlab.ui.container.TabGroup
        GraficasTab                 matlab.ui.container.Tab
        UIAxes_f2                   matlab.ui.control.UIAxes
        UIAxes_t2                   matlab.ui.control.UIAxes
        UIAxes_f1                   matlab.ui.control.UIAxes
        UIAxes_t1                   matlab.ui.control.UIAxes
        ChatBotTab                  matlab.ui.container.Tab
        UIAxes_12                   matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        recorder %  Objeto para almacenar el grabador de audio
        fileNames = {} % Celda para almacenar los nombres de los archivos grabados
        frecuencia = 1000;      % Valor por inicial
        amplitud = 1;
        duracion = 1;
        fs = 44100;
        L = 1;
        M = 1;
        sig1;
        sig1_fs;
        sig1_t_max;             %Señal 1
        sig2;
        sig2_fs;
        sig2_t_max;             %Señal 2
    end
    methods (Access = public)

        % Startup function
        function StartupFcn(app)
            %screenSize = get(0, 'ScreenSize');
            % Ajustar la posición de la figura de la aplicación al tamaño de la pantalla
            %app.UIFigure.Position = [1 1 screenSize(3) screenSize(4)];
            % Definir el directorio de los archivos .wav
            directoryPath = 'audios/';  % Ruta al directorio de los archivos de audio
            % Obtener todos los archivos .wav del directorio especificado
            wavFiles = dir(fullfile(directoryPath, '*.wav'));
            % Crear una lista de las rutas completas de los archivos .wav
            wavFileNames = fullfile({wavFiles.folder}, {wavFiles.name});
            % Actualizar la lista con los archivos del directorio actual
            app.AllFilesListBox.Items = wavFileNames; % Mostrar las rutas completas de archivos .wav en la lista
        end
        function [grafica_t, grafica_f] = grafica_vacia(app)
            opcion = app.Switch.Value;
            disp(opcion);
            switch opcion
                case 'Grafica 1'
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
                case 'Grafica 2'
                    grafica_t = app.UIAxes_t2;
                    grafica_f = app.UIAxes_f2;
                otherwise   
                    grafica_t = app.UIAxes_t1;
                    grafica_f = app.UIAxes_f1;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupFcn();
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            fs = 44100; % Frecuencia de muestreo
            nBits = 16; % Número de bits por muestra
            nChannels = 1; % Número de canales (1 para mono)

            % Crear el objeto audiorecorder
            app.recorder = audiorecorder(fs, nBits, nChannels);

            % Iniciar la grabación
            record(app.recorder);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            % Detener la grabación
            stop(app.recorder);

            % Obtener los datos de audio grabados
            audioData = getaudiodata(app.recorder);

            % Guardar el archivo de audio como "grabacion.wav"
            timestamp = datetime('now', 'Format', 'HH_mm_ddMMyyyy');
            filename = ['audios/grabacion_' char(timestamp) '.wav'];

            % Guardar el archivo de audio
            audiowrite(filename, audioData, app.recorder.SampleRate);

            [grafica_t,grafica_f]=grafica_vacia(app);
             % Graficar la forma de onda del audio en el eje definido en la aplicación
            t = (0:length(audioData)-1) / app.recorder.SampleRate; % Eje temporal
            plot(grafica_t, t, audioData);
            xlabel(grafica_t, 'Tiempo (s)');
            ylabel(grafica_t, 'Amplitud');
            title(grafica_t, 'Forma de onda del Audio');

            % Calcular y graficar el espectro de frecuencia en el otro eje definido
            L = length(audioData);
            NFFT = 2^nextpow2(L); % Tamaño de FFT
            Y = fft(audioData, NFFT) / L;
            f = app.recorder.SampleRate / 2 * linspace(0, 1, NFFT/2+1); % Eje de frecuencias
            plot(grafica_f, f, 2*abs(Y(1:NFFT/2+1)));
            xlabel(grafica_f, 'Frecuencia (Hz)');
            ylabel(grafica_f, '|Amplitud|');
            title(grafica_f, 'Espectro de Frecuencia');

             % Actualizar la lista de nombres de archivos y la lista desplegable
            app.fileNames{end+1} = filename; % Añadir el nuevo nombre a la celda
            app.LastUsedList.Items = app.fileNames; % Actualizar la lista con los nombres

            %Actualizar la lista de ficheros .wav en el directorio actual
            app.StartupFcn();
        end

        % Double-clicked callback: LastUsedList
        function LastUsedListDoubleClicked(app, event)
            selectedFile = app.LastUsedList.Value;

            if ~isempty(selectedFile)
                [audioData, fs] = audioread(selectedFile);

                [grafica_t,grafica_f]=grafica_vacia(app);
                % Graficar la forma de onda del archivo seleccionado en el eje definido en la aplicación
                t = (0:length(audioData)-1) / fs; % Eje temporal
                plot(grafica_t, t, audioData);
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                title(grafica_t, ['Forma de onda de: ', selectedFile]);

                % Calcular y graficar el espectro de frecuencia del archivo seleccionado
                L = length(audioData);
                NFFT = 2^nextpow2(L); % Tamaño de FFT para eficiencia
                Y = fft(audioData, NFFT) / L; % Transformada de Fourier normalizada
                f = fs / 2 * linspace(0, 1, NFFT/2+1); % Eje de frecuencias

                % Graficar el espectro en el eje definido en la aplicación
                plot(grafica_f, f, 2*abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, ['Espectro de Frecuencia de: ', selectedFile]);
            end
        end

        % Double-clicked callback: AllFilesListBox
        function AllFilesListBoxDoubleClicked(app, event)
            selectedFile = app.AllFilesListBox.Value;

            if ~isempty(selectedFile)
                [audioData, fs] = audioread(selectedFile);
    
                [grafica_t,grafica_f]=grafica_vacia(app);
                % Graficar la forma de onda del archivo seleccionado en el eje definido en la aplicación
                t = (0:length(audioData)-1) / fs; % Eje temporal
                plot(grafica_t, t, audioData);
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                title(grafica_t, ['Forma de onda de: ', selectedFile]);

                % Calcular y graficar el espectro de frecuencia del archivo seleccionado
                L = length(audioData);
                NFFT = 2^nextpow2(L); % Tamaño de FFT para eficiencia
                Y = fft(audioData, NFFT) / L; % Transformada de Fourier normalizada
                f = fs / 2 * linspace(0, 1, NFFT/2+1); % Eje de frecuencias

                % Graficar el espectro en el eje definido en la aplicación
                plot(grafica_f, f, 2*abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, ['Espectro de Frecuencia de: ', selectedFile]);
            end

        end

        % Clicked callback: SealDropDown
        function SealDropDownClicked(app, event)
            % Almacena amplitud y frecuencia en variables locales
            [grafica_t, grafica_f] = grafica_vacia(app);
            A = app.amplitud;
            f = app.frecuencia;
            Fs = app.fs;
            t_max = app.duracion;
            y = [];

            % Muestra los valores de frecuencia y amplitud para verificación
            disp(['Frecuencia: ', num2str(f)]);
            disp(['Amplitud: ', num2str(A)]);

            % Verificación de frecuencia cero
            if f == 0 || Fs == 0
                % Si la frecuencia es 0, se muestra una línea constante con valor de amplitud
                cla(grafica_t);  % Limpia el gráfico temporal
                cla(grafica_f);  % Limpia el gráfico de frecuencia

                % Define el tiempo y la señal como una línea constante
                t = 0:0.01:t_max;
                y = A * ones(size(t));

                % Grafica la señal constante en el dominio temporal
                plot(grafica_t, t, y);
                title(grafica_t, 'Señal Constante (f = 0)');
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');

                % En el dominio de frecuencia, la transformada de Fourier de una señal constante
                % es un único valor en frecuencia cero
                plot(grafica_f, 0, abs(A), 'o');  % Valor constante en frecuencia 0
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, 'Espectro de Frecuencia (f = 0)');
                return;
            end

            % Obtiene el ítem seleccionado
            item = event.InteractionInformation.Item;
            item = string(item);

            % Ajuste del eje temporal según la frecuencia de la señal

            Ts = 1 / Fs;                     % Periodo de la señal
            t = 0:Ts:t_max;                % Cubre algunos ciclos para visualización adecuada
            % Limpiar gráficos previos
            cla(grafica_t);                % Limpia el gráfico temporal
            cla(grafica_f);                % Limpia el gráfico de frecuencia

            switch item
                case "1"  % Señal Senoidal
                    y = A * sin(2 * pi * f * t);
                    plot(grafica_t, t, y);
                    title(grafica_t, 'Señal Senoidal');
                    xlabel(grafica_t, 'Tiempo (s)');
                    ylabel(grafica_t, 'Amplitud');

                    % Dominio de la frecuencia
                    L = length(t);                       % Número de muestra
                    NFFT = 2^nextpow2(L);                % Tamaño de FFT para eficiencia
                    Y = fft(y, NFFT) / L;                % Transformada de Fourier normalizada
                    f_axis = Fs * (0:(NFFT/2)) / NFFT;   % Eje de frecuencias correctamente escalado

                    % Gráfico de la transformada de Fourier
                    plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1))); % Multiplicación por 2 para la amplitud real
                    xlabel(grafica_f, 'Frecuencia (Hz)');
                    ylabel(grafica_f, '|Amplitud|');
                    title(grafica_f, 'Espectro de Frecuencia');

                case "2"  % Señal Cosenoidal
                    y = A * cos(2 * pi * f * t);
                    plot(grafica_t, t, y);
                    title(grafica_t, 'Señal Cosenoidal');
                    xlabel(grafica_t, 'Tiempo (s)');
                    ylabel(grafica_t, 'Amplitud');


                    % Dominio de la frecuencia
                    L = length(t);                  % Número de muestras
                    NFFT = 2^nextpow2(L);           % Tamaño de FFT para eficiencia
                    Y = fft(y, NFFT) / L;           % Transformada de Fourier normalizada
                    f_axis = Fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias

                    % Gráfico de la transformada de Fourier
                    plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                    xlabel(grafica_f, 'Frecuencia (Hz)');
                    ylabel(grafica_f, '|Amplitud|');
                    title(grafica_f, ['Espectro de Frecuencia']);
                case "3"  % Onda Cuadrada
                    y = A * square(2 * pi * f * t);
                    plot(grafica_t, t, y);
                    title(grafica_t, 'Onda Cuadrada');
                    xlabel(grafica_t, 'Tiempo (s)');
                    ylabel(grafica_t, 'Amplitud');

                    % Dominio de la frecuencia
                    L = length(t);                  % Número de muestras
                    NFFT = 2^nextpow2(L);           % Tamaño de FFT para eficiencia
                    Y = fft(y, NFFT) / L;           % Transformada de Fourier normalizada
                    f_axis = Fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias

                    % Gráfico de la transformada de Fourier
                    plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                    xlabel(grafica_f, 'Frecuencia (Hz)');
                    ylabel(grafica_f, '|Amplitud|');
                    title(grafica_f, ['Espectro de Frecuencia']);

                case "4"  % Onda Triangular
                    y = A * sawtooth(2 * pi * f * t, 0.5);
                    plot(grafica_t, t, y);
                    title(grafica_t, 'Onda Triangular');
                    xlabel(grafica_t, 'Tiempo (s)');
                    ylabel(grafica_t, 'Amplitud');

                    % Dominio de la frecuencia
                    L = length(t);                  % Número de muestras
                    NFFT = 2^nextpow2(L);           % Tamaño de FFT para eficiencia
                    Y = fft(y, NFFT) / L;           % Transformada de Fourier normalizada
                    f_axis = Fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias

                    % Gráfico de la transformada de Fourier
                    plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                    xlabel(grafica_f, 'Frecuencia (Hz)');
                    ylabel(grafica_f, '|Amplitud|');
                    title(grafica_f, ['Espectro de Frecuencia']);

                case "5"  % Onda Diente de Sierra
                    y = A * sawtooth(2 * pi * f * t);
                    plot(grafica_t, t, y);
                    title(grafica_t, 'Onda Diente de Sierra');
                    xlabel(grafica_t, 'Tiempo (s)');
                    ylabel(grafica_t, 'Amplitud');

                    % Dominio de la frecuencia
                    L = length(t);                  % Número de muestras
                    NFFT = 2^nextpow2(L);           % Tamaño de FFT para eficiencia
                    Y = fft(y, NFFT) / L;           % Transformada de Fourier normalizada
                    f_axis = Fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias

                    % Gráfico de la transformada de Fourier
                    plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                    xlabel(grafica_f, 'Frecuencia (Hz)');
                    ylabel(grafica_f, '|Amplitud|');
                    title(grafica_f, ['Espectro de Frecuencia']);
            end
                opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = Fs;
                app.sig1_t_max = t_max;
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = Fs;
                app.sig2_t_max = t_max;
            end
        end

        % Value changed function: FrecuenciaHzEditField
        function FrecuenciaHzEditFieldValueChanged(app, event)
             app.frecuencia = app.FrecuenciaHzEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva frecuencia: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Value changed function: AmplitudEditField
        function AmplitudEditFieldValueChanged(app, event)
            app.amplitud = app.AmplitudEditField.Value;  % Guarda el valor en la propiedad
            disp(['Nueva amplitud: ', num2str(app.amplitud)]);  % Verifica el valor
        end

        % Value changed function: DuracionsEditField
        function DuracionsEditFieldValueChanged(app, event)
            app.duracion = app.DuracionsEditField.Value;
            disp(['Nueva Duracion: ', num2str(app.duracion)]);  % Verifica el valor
        end

        % Value changed function: FsHzEditField
        function FsHzEditFieldValueChanged(app, event)
            app.fs = app.FsHzEditField.Value;
            disp(['Nueva fs: ', num2str(app.frecuencia)]);  % Verifica el valor
        end

        % Button pushed function: SumaButton
        function SumaButtonPushed(app, event)
            [grafica_t, grafica_f] = grafica_vacia(app);
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
            % Remuestrear la segunda señal para coincidir con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);

            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end

            % Sumar las señales
            sum_signal = app.sig1 + sig2_remuestreada;

            % Almacenar la señal sumada en la variable de salida y los parámetros
            y = sum_signal;
            fs = app.sig1_fs;
            t = 0:1/fs:(length(sum_signal) - 1) / fs; % Eje de tiempo con el mismo número de muestras

            % Gráfica en el dominio del tiempo
            plot(grafica_t, t, y);
            title(grafica_t, 'Señal Suma');
            xlabel(grafica_t, 'Tiempo (s)');
            ylabel(grafica_t, 'Amplitud');

            % Dominio de la frecuencia
            L = length(t);                    % Número de muestras
            NFFT = 2^nextpow2(L);             % Tamaño de FFT para eficiencia en potencia de 2
            Y = fft(y, NFFT) / L;             % Transformada de Fourier normalizada
            f_axis = fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2

            % Gráfico de la transformada de Fourier
            plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
            xlabel(grafica_f, 'Frecuencia (Hz)');
            ylabel(grafica_f, '|Amplitud|');
            title(grafica_f, 'Espectro de Frecuencia');

               opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(sum_signal) - 1) / fs;
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(sum_signal) - 1) / fs;
            end

        end

        % Button pushed function: MulButton
        function MulButtonPushed(app, event)
            [grafica_t, grafica_f] = grafica_vacia(app);
            % Verificar que las señales no estén vacías
            if isempty(app.sig1) || isempty(app.sig2)
                error('Las señales no pueden estar vacías.');
            end
            % Remuestrear la segunda señal para que coincida con la frecuencia de la primera señal
            sig2_remuestreada = resample(app.sig2, app.sig1_fs, app.sig2_fs);
            % Ajustar la duración de ambas señales (relleno con ceros si es necesario)
            if length(app.sig1) < length(sig2_remuestreada)
                app.sig1 = [app.sig1, zeros(1, length(sig2_remuestreada) - length(app.sig1))];
            elseif length(sig2_remuestreada) < length(app.sig1)
                sig2_remuestreada = [sig2_remuestreada, zeros(1, length(app.sig1) - length(sig2_remuestreada))];
            end
            % Multiplicar las señales
            mult_signal = app.sig1 .* sig2_remuestreada;
            % Almacenar la señal multiplicada en la variable de salida y los parámetros
            y = mult_signal;
            fs = app.sig1_fs;
            t = 0:1/fs:(length(mult_signal) - 1) / fs; % Eje de tiempo con el mismo número de muestras
            % Gráfica en el dominio del tiempo
            plot(grafica_t, t, y);
            title(grafica_t, 'Señal Multiplicada');
            xlabel(grafica_t, 'Tiempo (s)');
            ylabel(grafica_t, 'Amplitud');
            % Dominio de la frecuencia
            L = length(t);                    % Número de muestras
            NFFT = 2^nextpow2(L);             % Tamaño de FFT para eficiencia en potencia de 2
            Y = fft(y, NFFT) / L;             % Transformada de Fourier normalizada
            f_axis = fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2
            % Gráfico de la transformada de Fourier
            plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
            xlabel(grafica_f, 'Frecuencia (Hz)');
            ylabel(grafica_f, '|Amplitud|');
            title(grafica_f, 'Espectro de Frecuencia');

            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = y;
                app.sig1_fs = fs;
                app.sig1_t_max = (length(mult_signal) - 1) / fs;
            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = y;
                app.sig2_fs = fs;
                app.sig2_t_max = (length(mult_signal) - 1) / fs;
            end

        end

        % Button pushed function: DiezmarButton
        function DiezmarButtonPushed(app, event)
            M = app.M;
            [grafica_t, grafica_f] = grafica_vacia(app);
            if M <= 0
                error('El factor de diezmo M debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = downsample(app.sig1, M);
                app.sig1_fs = app.sig1_fs / M;
                t = 0:1/app.sig1_fs:app.sig1_t_max; % Eje de tiempo con el mismo número de muestras
                % Gráfica en el dominio del tiempo
                plot(grafica_t, t, app.sig1);
                title(grafica_t, 'Señal Diezmada');
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                % Dominio de la frecuencia
                L = length(t);                    % Número de muestras
                NFFT = 2^nextpow2(L);             % Tamaño de FFT para eficiencia en potencia de 2
                Y = fft(app.sig1, NFFT) / L;             % Transformada de Fourier normalizada
                f_axis = app.sig1_fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2
                % Gráfico de la transformada de Fourier
                plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, 'Espectro de Frecuencia');

            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = downsample(app.sig2, M);
                app.sig2_fs = app.sig2_fs / M;
                t = 0:1/app.sig2_fs:app.sig2_t_max; % Eje de tiempo con el mismo número de muestras
                % Gráfica en el dominio del tiempo
                plot(grafica_t, t, app.sig2);
                title(grafica_t, 'Señal Diezmada');
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                % Dominio de la frecuencia
                L = length(t);                    % Número de muestras
                NFFT = 2^nextpow2(L);             % Tamaño de FFT para eficiencia en potencia de 2
                Y = fft(app.sig2, NFFT) / L;             % Transformada de Fourier normalizada
                f_axis = app.sig2_fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2
                % Gráfico de la transformada de Fourier
                plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, 'Espectro de Frecuencia');

            end
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.M = app.MEditField.Value;
            
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            app.L = app.LEditField.Value;
            
        end

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
            L = app.L;
            [grafica_t, grafica_f] = grafica_vacia(app);
            if L <= 0
                error('El factor de interpolacion L debe ser mayor a 0');
            end
            opcion = app.Switch.Value;
            if strcmp(opcion, 'Grafica 1')
                app.sig1 = interp(app.sig1, L);
                app.sig1_fs = app.sig1_fs * L;
                t = linspace(0,app.sig1_t_max,length(app.sig1));% Eje de tiempo con el mismo número de muestras

                plot(grafica_t, t, app.sig1);
                title(grafica_t, 'Señal Interpolada');
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                % Dominio de la frecuencia
                Le = length(t);                    % Número de muestras
                NFFT = 2^nextpow2(Le);             % Tamaño de FFT para eficiencia en potencia de 2
                Y = fft(app.sig1, NFFT) / Le;             % Transformada de Fourier normalizada
                f_axis = app.sig1_fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2
                % Gráfico de la transformada de Fourier
                plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, 'Espectro de Frecuencia');

            elseif strcmp(opcion, 'Grafica 2')
                app.sig2 = interp(app.sig2, L);
                app.sig2_fs = app.sig2_fs * L;
                t = linspace(0,app.sig2_t_max,length(app.sig2)); % Eje de tiempo con el mismo número de muestras
                % Gráfica en el dominio del tiempo
                plot(grafica_t, t, app.sig2);
                title(grafica_t, 'Señal Interpolada');
                xlabel(grafica_t, 'Tiempo (s)');
                ylabel(grafica_t, 'Amplitud');
                % Dominio de la frecuencia
                Le = length(t);                    % Número de muestras
                NFFT = 2^nextpow2(Le);             % Tamaño de FFT para eficiencia en potencia de 2
                Y = fft(app.sig2, NFFT) / Le;             % Transformada de Fourier normalizada
                f_axis = app.sig2_fs / 2 * linspace(0, 1, NFFT / 2 + 1); % Eje de frecuencias hasta fs/2
                % Gráfico de la transformada de Fourier
                plot(grafica_f, f_axis, 2 * abs(Y(1:NFFT/2+1)));
                xlabel(grafica_f, 'Frecuencia (Hz)');
                ylabel(grafica_f, '|Amplitud|');
                title(grafica_f, 'Espectro de Frecuencia');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_12 = uiaxes(app.UIFigure);
            title(app.UIAxes_12, 'Title')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficasTab = uitab(app.TabGroup);
            app.GraficasTab.Title = 'Graficas';
            app.UIAxes_t1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t1, 'Title')
            xlabel(app.UIAxes_t1, 'X')
            ylabel(app.UIAxes_t1, 'Y')
            zlabel(app.UIAxes_t1, 'Z')
            app.UIAxes_f1 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f1, 'Title')
            xlabel(app.UIAxes_f1, 'X')
            ylabel(app.UIAxes_f1, 'Y')
            zlabel(app.UIAxes_f1, 'Z')
            app.UIAxes_t2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_t2, 'Title')
            xlabel(app.UIAxes_t2, 'X')
            ylabel(app.UIAxes_t2, 'Y')
            zlabel(app.UIAxes_t2, 'Z')
            app.UIAxes_f2 = uiaxes(app.GraficasTab);
            title(app.UIAxes_f2, 'Title')
            xlabel(app.UIAxes_f2, 'X')
            ylabel(app.UIAxes_f2, 'Y')
            zlabel(app.UIAxes_f2, 'Z')
            app.ChatBotTab = uitab(app.TabGroup);
            app.ChatBotTab.Title = 'ChatBot';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.GrabarTab = uitab(app.TabGroup2);
            app.GrabarTab.Title = 'Grabar';
            app.ReproducirPanel = uipanel(app.GrabarTab);
            app.ReproducirPanel.Title = 'Reproducir';
            app.UIAxes = uiaxes(app.ReproducirPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button_2 = uibutton(app.ReproducirPanel, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'img', 'b_reproducir.png');
            app.Button_2.Text = '';
            app.Button_9 = uibutton(app.ReproducirPanel, 'push');
            app.Button_9.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.ReproducirPanel, 'push');
            app.Button_10.Icon = fullfile(pathToMLAPP, 'img', 'b_pausar.png');
            app.Button_10.Text = '';
            app.GrabarPanel = uipanel(app.GrabarTab);
            app.GrabarPanel.Title = 'Grabar';
            app.Button_7 = uibutton(app.GrabarPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'img', 'b_grabar.png');
            app.Button_7.Text = '';
            app.Button_11 = uibutton(app.GrabarPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'img', 'b_detener.png');
            app.Button_11.Text = '';
            app.GenerarPanel = uipanel(app.GrabarTab);
            app.GenerarPanel.Title = 'Generar';
            app.SealDropDownLabel = uilabel(app.GenerarPanel);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.GenerarPanel);
            app.SealDropDown.Items = {'Seno', 'Coseno', 'Onda Cuadrada', 'Onda Triangular', 'Diente de Sierra'};
            app.SealDropDown.ClickedFcn = createCallbackFcn(app, @SealDropDownClicked, true);
            app.SealDropDown.Value = 'Seno';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FrecuenciaHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciaHzEditFieldValueChanged, true);
            app.FrecuenciaHzEditField.Value = 1000;
            app.AmplitudEditFieldLabel = uilabel(app.GenerarPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudEditFieldValueChanged, true);
            app.AmplitudEditField.Value = 1;
            app.DuracionsEditFieldLabel = uilabel(app.GenerarPanel);
            app.DuracionsEditFieldLabel.Text = 'Duracion (s)';
            app.DuracionsEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.DuracionsEditField.ValueChangedFcn = createCallbackFcn(app, @DuracionsEditFieldValueChanged, true);
            app.DuracionsEditField.Value = 1;
            app.FsHzEditFieldLabel = uilabel(app.GenerarPanel);
            app.FsHzEditFieldLabel.Text = 'Fs (Hz)';
            app.FsHzEditField = uieditfield(app.GenerarPanel, 'numeric');
            app.FsHzEditField.ValueChangedFcn = createCallbackFcn(app, @FsHzEditFieldValueChanged, true);
            app.FsHzEditField.Value = 44100;
            app.Switch = uiswitch(app.GrabarTab, 'slider');
            app.Switch.Items = {'Grafica 1', 'Grafica 2'};
            app.Switch.Value = 'Grafica 1';
            app.OperarPanel = uipanel(app.GrabarTab);
            app.OperarPanel.Title = 'Operar';
            app.SumaButton = uibutton(app.OperarPanel, 'push');
            app.SumaButton.ButtonPushedFcn = createCallbackFcn(app, @SumaButtonPushed, true);
            app.SumaButton.Text = 'Suma';
            app.MulButton = uibutton(app.OperarPanel, 'push');
            app.MulButton.ButtonPushedFcn = createCallbackFcn(app, @MulButtonPushed, true);
            app.MulButton.Text = 'Mul';
            app.DiezmarButton = uibutton(app.OperarPanel, 'push');
            app.DiezmarButton.ButtonPushedFcn = createCallbackFcn(app, @DiezmarButtonPushed, true);
            app.DiezmarButton.Text = 'Diezmar';
            app.InterpolarButton = uibutton(app.OperarPanel, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.MEditFieldLabel = uilabel(app.OperarPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.OperarPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.OperarPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.OperarPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.FiltarTab = uitab(app.TabGroup2);
            app.FiltarTab.Title = 'Filtar';
            app.RuidoTab = uitab(app.TabGroup2);
            app.RuidoTab.Title = 'Ruido';
            app.TabGroup4 = uitabgroup(app.UIFigure);
            app.PropiedadesTab = uitab(app.TabGroup4);
            app.PropiedadesTab.Title = 'Propiedades';
            app.PianoTab = uitab(app.TabGroup4);
            app.PianoTab.Title = 'Piano';
            app.TabGroup5 = uitabgroup(app.UIFigure);
            app.AllFilesTab = uitab(app.TabGroup5);
            app.AllFilesTab.Title = 'All Files';
            app.AllFilesListBox = uilistbox(app.AllFilesTab);
            app.AllFilesListBox.Items = {};
            app.AllFilesListBox.DoubleClickedFcn = createCallbackFcn(app, @AllFilesListBoxDoubleClicked, true);
            app.AllFilesListBox.Value = {};
            app.LastUsedTab = uitab(app.TabGroup5);
            app.LastUsedTab.Title = 'Last Used';
            app.LastUsedList = uilistbox(app.LastUsedTab);
            app.LastUsedList.Items = {};
            app.LastUsedList.DoubleClickedFcn = createCallbackFcn(app, @LastUsedListDoubleClicked, true);
            app.LastUsedList.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end